classdef Dashboard < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DashboardUIFigure               matlab.ui.Figure
        SaveButton                      matlab.ui.control.Button
        EditField_6                     matlab.ui.control.NumericEditField
        selected_2                      matlab.ui.control.Label
        high_2                          matlab.ui.control.Label
        current_2                       matlab.ui.control.Label
        Label_78                        matlab.ui.control.Label
        Label_12                        matlab.ui.control.Label
        Label_1212                      matlab.ui.control.Label
        Label_111111                    matlab.ui.control.Label
        Label_1010                      matlab.ui.control.Label
        Label_99                        matlab.ui.control.Label
        Label_88                        matlab.ui.control.Label
        Label_1111                      matlab.ui.control.Label
        Label_10                        matlab.ui.control.Label
        Label_9                         matlab.ui.control.Label
        Label_8                         matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        Kneeabductionmoment90cutsLabel  matlab.ui.control.Label
        xButton                         matlab.ui.control.Button
        EditField                       matlab.ui.control.NumericEditField
        selected                        matlab.ui.control.Label
        high                            matlab.ui.control.Label
        current                         matlab.ui.control.Label
        Label_66                        matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        DropDown_11                     matlab.ui.control.DropDown
        Kneeabductionmoment180cutsLabel  matlab.ui.control.Label
        DropDown_10                     matlab.ui.control.DropDown
        DropDown_9                      matlab.ui.control.DropDown
        DropDown_8                      matlab.ui.control.DropDown
        Label_11                        matlab.ui.control.Label
        Label_55                        matlab.ui.control.Label
        Label_44                        matlab.ui.control.Label
        Label_33                        matlab.ui.control.Label
        Label_22                        matlab.ui.control.Label
        TechniqueVariables90cutsLabel   matlab.ui.control.Label
        TechniqueVariables180cutsLabel  matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        LoadFile                        matlab.ui.control.Button
        DropDown_7                      matlab.ui.control.DropDown
        EditField_1U_2                  matlab.ui.control.NumericEditField
        EditField_1O_2                  matlab.ui.control.NumericEditField
        DropDown_6                      matlab.ui.control.DropDown
        DropDown_2                      matlab.ui.control.DropDown
        DropDown_1                      matlab.ui.control.DropDown
        Label_1                         matlab.ui.control.Label
        DropDown_5                      matlab.ui.control.DropDown
        DropDown_4                      matlab.ui.control.DropDown
        DropDown_3                      matlab.ui.control.DropDown
        EditField_1U_5                  matlab.ui.control.NumericEditField
        EditField_1O_5                  matlab.ui.control.NumericEditField
        UIAxes6_90                      matlab.ui.control.UIAxes
        UIAxes1_90                      matlab.ui.control.UIAxes
        UIAxes1                         matlab.ui.control.UIAxes
        UIAxes2_90                      matlab.ui.control.UIAxes
        UIAxes5_90                      matlab.ui.control.UIAxes
        UIAxes4_90                      matlab.ui.control.UIAxes
        UIAxes3_90                      matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes6                         matlab.ui.control.UIAxes
        UIAxes22                        matlab.ui.control.UIAxes
        UIAxes5                         matlab.ui.control.UIAxes
        UIAxes4                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
    end


    properties (Access = public)
        datacontainer; %stores the Initial path and the choosen conditions and participants from the main app
        datastorage;
        handle1;
        handle2;
        handle3;
        handle4;
        handle5;
        handle6;
        path_to_file;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, datastruct)
            clc
            % app.DashboardUIFigure.WindowState = 'maximized';
            root = groot;
            % Get the screen size
            screenSize = root.ScreenSize;
            % Extract width and height
            screenWidth = screenSize(3);
            screenHeight = screenSize(4)-20;
            figureWidth = screenWidth; % Width of your figure
            figureHeight = screenHeight; % Height of your figure
            figureX = (screenWidth - figureWidth) / 2; % Center figure horizontally
            figureY = (screenHeight - figureHeight) / 2; % Center figure vertically
            app.DashboardUIFigure.Position = [figureX, figureY, figureWidth, figureHeight];
            %app.datacontainer = load('C:\Users\adpatrick\Downloads\P008.mat');%datastruct;
            try
                app.datacontainer = load(datastruct);
                path_to_file = datastruct;
            catch
                try
                    [file,location] = uigetfile('*.mat');
                    app.datacontainer =load ([location, '/', file]);
                    path_to_file = [location, '/', file];
                catch
                    msgbox("Select a valid mat file!","Select file","error");
                end
            end

            if strcmp(app.datacontainer.OPTIONS.SETTINGS, 'OSLO')
                app.Kneeabductionmoment180cutsLabel.Text = 'Knee abduction moment straight';
                app.TechniqueVariables180cutsLabel.Text = 'Technique Variables straight';
                app.Kneeabductionmoment90cutsLabel.Text = 'Knee abduction moment side';
                app.TechniqueVariables90cutsLabel.Text = 'Technique Variables side';
            elseif strcmp(app.datacontainer.OPTIONS.SETTINGS, 'CALGARY')
                app.Kneeabductionmoment180cutsLabel.Text = 'Knee abduction moment 180° cuts';
                app.TechniqueVariables180cutsLabel.Text = 'Technique Variables 180° cuts';
                app.Kneeabductionmoment90cutsLabel.Text = 'Knee abduction moment 90° cuts';
                app.TechniqueVariables90cutsLabel.Text = 'Technique Variables 90° cuts';
            else
                app.Kneeabductionmoment180cutsLabel.Text = 'Knee abduction moment Task 1';
                app.TechniqueVariables180cutsLabel.Text = 'Technique Variables Task 1';
                app.Kneeabductionmoment90cutsLabel.Text = 'Knee abduction moment Task 2';
                app.TechniqueVariables90cutsLabel.Text = 'Technique Variables Task 2';
            end
            %% frage nach welcher task das lezte file war
            trialnames = fieldnames(app.datacontainer.OPTIONS.TASK_ID_NUMERIC);
            current_task_ID= app.datacontainer.OPTIONS.TASK_ID_NUMERIC.(trialnames{end});
            current_task_name = (trialnames{end});
            current_task_pos = length(trialnames);

            %% pack all into one matrix
            for tr = 1 : length (trialnames)
                matrix(tr,1) = double(app.datacontainer.OPTIONS.ANALYZEDLEG_BIN.(trialnames{tr, 1})) ;
                matrix(tr,2) = double(app.datacontainer.OPTIONS.WAS_VALID.(trialnames{tr, 1}));
                matrix(tr,3) =double(app.datacontainer.OPTIONS.Instruction.(trialnames{tr, 1}));
                matrix(tr,4) =double(app.datacontainer.OPTIONS.TASK_ID_NUMERIC.(trialnames{tr, 1}));
                if matrix(tr,1)==1 & matrix(tr,2) ==1 %& matrix(tr,3) ==0 % wenn rechtes bein und ohne feedback und valid
                    col{tr,1} = [0, 0,0];
                    col2{tr,1} = [0, 0,0];
                elseif matrix(tr,1)==0 & matrix(tr,2) ==1% & matrix(tr,3) ==0 % wenn rechtes bein und ohne feedback und valid
                    col{tr,1} = [192,192,192]/255;
                    col2{tr,1} = [192,192,192]/255;
                else
                    col{tr,1} = [1,1,1];
                    col2{tr,1} = [1,0.5,1];
                end
            end
            matrix(:,5) = [1:length(trialnames)]'; % is the original trial number count for findex

            %% PLOT KAM curves
            plot(app.UIAxes6,app.datacontainer.NORMAL.KAM(:,[matrix((find(matrix(:,4)==1 & matrix(:,2)==1)),5)]), 'k', linewidth=1)
            hold(app.UIAxes6, 'on');
            plot(app.UIAxes6,app.datacontainer.NORMAL.KAM(:,max([matrix((find(matrix(:,4)==1 & matrix(:,2)==1)),5)])), 'k', linewidth=3)
            ylabel(app.UIAxes6, 'KAM [Nm/kg]', 'Interpreter','none', 'FontWeight', 'bold');
            xlim(app.UIAxes6,[1,201]);
            set(app.UIAxes6, 'Xtick', [1:50:201]);
            set(app.UIAxes6, 'XTickLabel', {'0', '25', '50', '75', '100'});
            xlabel(app.UIAxes6,'[ms of stance]');
            hold(app.UIAxes6, 'on');
            yline(app.UIAxes6,0, Color=[0 0 0],  LineStyle="--");
            plot(app.UIAxes6_90,app.datacontainer.NORMAL.KAM(:,[matrix((find(matrix(:,4)==2 & matrix(:,2)==1)),5)]), 'k', linewidth=1)
            hold(app.UIAxes6_90, 'on');
            plot(app.UIAxes6_90,app.datacontainer.NORMAL.KAM(:,max([matrix((find(matrix(:,4)==2 & matrix(:,2)==1)),5)])), 'k', linewidth=3)
            ylabel(app.UIAxes6_90, 'KAM [Nm/kg]', 'Interpreter','none', 'FontWeight', 'bold');
            xlim(app.UIAxes6_90,[1,201]);
            set(app.UIAxes6_90, 'Xtick', [1:50:201]);
            set(app.UIAxes6_90, 'XTickLabel', {'0', '25', '50', '75', '100'});
            xlabel(app.UIAxes6_90,'[ms of stance]');
            hold(app.UIAxes6_90, 'on');
            yline(app.UIAxes6_90,0, Color=[0 0 0], LineStyle="--");

            [no_trials, ~]=size(matrix);

            % was right was valid and no instruduction and task 1
            idxr_180 = find(matrix(:,1) ==1 & (matrix(:,2) ==1) & (matrix(:,3) ==0)& (matrix(:,4) ==1));
            idxl_180 = find(matrix(:,1) ==0 & (matrix(:,2) ==1) & (matrix(:,3) ==0)&(matrix(:,4) ==1));
            % was right was valid and no instruduction and task 2
            idxr_90 = find(matrix(:,1) ==1 & (matrix(:,2) ==1) & (matrix(:,3) ==0)& (matrix(:,4) ==2));
            idxl_90 = find(matrix(:,1) ==0 & (matrix(:,2) ==1) & (matrix(:,3) ==0)&(matrix(:,4) ==2));

            % was right was valid and no instruduction and task 1
            idxr_180_w_f = find(matrix(:,1) ==1 & (matrix(:,2) ==1) & (matrix(:,3) ==1)& (matrix(:,4) ==1));
            idxl_180_w_f = find(matrix(:,1) ==0 & (matrix(:,2) ==1) & (matrix(:,3) ==1)&(matrix(:,4) ==1));
            % was right was valid and no instruduction and task 2
            idxr_90_w_f = find(matrix(:,1) ==1 & (matrix(:,2) ==1) & (matrix(:,3) ==1)& (matrix(:,4) ==2));
            idxl_90_w_f = find(matrix(:,1) ==0 & (matrix(:,2) ==1) & (matrix(:,3) ==1)&(matrix(:,4) ==2));


            %% get the mean KAM for L and R wo feedback
            R_mean_KAM_woF_180= mean(app.datacontainer.PARAMETERS.PEAK_KNEE_X(matrix(idxr_180,5)));
            L_mean_KAM_woF_180 = mean(app.datacontainer.PARAMETERS.PEAK_KNEE_X(matrix(idxl_180,5)));

            R_mean_KAM_woF_90= mean(app.datacontainer.PARAMETERS.PEAK_KNEE_X(matrix(idxr_90,5)));
            L_mean_KAM_woF_90 = mean(app.datacontainer.PARAMETERS.PEAK_KNEE_X(matrix(idxl_90,5)));


            for tr = 1 : length (trialnames)
                if matrix(tr,1)==1 & matrix(tr,2) ==1 & matrix(tr,3) ==1 & (app.datacontainer.PARAMETERS.PEAK_KNEE_X(tr)<=R_mean_KAM_woF_180*0.9)
                    col{tr,1} = [0,128,0]/255; % dunkel gruen
                elseif matrix(tr,1)==1 & matrix(tr,2) ==1 & matrix(tr,3) ==1 & (app.datacontainer.PARAMETERS.PEAK_KNEE_X(tr)>=R_mean_KAM_woF_180*1.1)
                    col{tr,1} = [220,20,60]/255;
                elseif matrix(tr,1)==0 & matrix(tr,2) ==1 & matrix(tr,3) ==1 & (app.datacontainer.PARAMETERS.PEAK_KNEE_X(tr)<=L_mean_KAM_woF_180*0.9)
                    col{tr,1} = [0,255,0]/255;
                elseif matrix(tr,1)==0 & matrix(tr,2) ==1 & matrix(tr,3) ==1 & (app.datacontainer.PARAMETERS.PEAK_KNEE_X(tr)>=L_mean_KAM_woF_180*1.1)
                    col{tr,1} = [255,99,71]/255;
                else
                    col{tr,1} = col{tr,1};
                end
            end
            app.datastorage.matrix = matrix;
            app.datastorage.no_trials = no_trials;
            app.datastorage.idxr_180 = idxr_180;
            app.datastorage.idxl_180 = idxl_180;
            app.datastorage.idxl_90 = idxl_90;
            app.datastorage.idxr_90 =idxr_90;
            app.datastorage.col = col;
            app.datastorage.col2 = col2;
            app.datastorage.idxr_180_w_f = idxr_180_w_f;
            app.datastorage.idxl_180_w_f=idxl_180_w_f;
            app.datastorage.idxr_90_w_f=idxr_90_w_f;
            app.datastorage.idxl_90_w_f=idxl_90_w_f;
            bar_sub_plot('PEAK_KNEE_X','UIAxes1_90', 'UIAxes1', matrix,  idxr_180, idxl_180,idxr_90, idxl_90,no_trials, col ,'pKAM [Nm/kg]', '', idxr_180_w_f,idxl_180_w_f,idxr_90_w_f,idxl_90_w_f,app)

            bar_sub_plot('TRUNK_LEAN_AT_IC','UIAxes2_90', 'UIAxes2', matrix,  idxr_180, idxl_180,idxr_90, idxl_90,no_trials, col2 ,'Lateral trunk lean [deg]', '(-) outward / (+) inward', idxr_180_w_f,idxl_180_w_f,idxr_90_w_f,idxl_90_w_f,app)

            bar_sub_plot('KNEE_VALGUS_AT_IC','UIAxes3_90', 'UIAxes3', matrix,  idxr_180, idxl_180,idxr_90, idxl_90,no_trials, col2 ,'Frontal plane knee angle [deg]', '(-) valgus / (+) varus', idxr_180_w_f,idxl_180_w_f,idxr_90_w_f,idxl_90_w_f,app)

            bar_sub_plot('FOOT_STRIKE_PATTERN','UIAxes4_90', 'UIAxes4', matrix,  idxr_180, idxl_180,idxr_90, idxl_90,no_trials, col2 ,'Foot strike angle [deg]', '(-) rearfoot / (+) forefoot', idxr_180_w_f,idxl_180_w_f,idxr_90_w_f,idxl_90_w_f,app)


            app.DropDown_5.Items = flip(fieldnames(app.datacontainer.PARAMETERS));
            app.DropDown_10.Items = flip(fieldnames(app.datacontainer.PARAMETERS));


            % %% first plot the mean KAM in each figure
            %
            % %% make all in a loop todo all figures will be made in a loop and assignied depending on matrix entry in the correct field
            %
            % if current_task_ID ==1
            %     %% plotte mean mit feedback
            %     [~, pos]=max(app.datacontainer.PARAMETERS.PEAK_KNEE_X);
            %     %% add textlabels
            %     app.Label_1.Text = ['Cutting Angle: ', num2str(round(app.datacontainer.PARAMETERS.CuttingAngle(end),1))];
            %     app.Label_11.Text =num2str(round(app.datacontainer.PARAMETERS.CuttingAngle(pos),1));
            %     %%
            %     app.Label_2.Text = ['Approach Speed: ', num2str(round(app.datacontainer.PARAMETERS.VELOCITY(end),1))];
            %     app.Label_22.Text =num2str(round(app.datacontainer.PARAMETERS.VELOCITY(pos),1));
            %     %%
            %     app.Label_3.Text = ['ResGRF: ', num2str(round(app.datacontainer.PARAMETERS.RESGRFatPeakKAM(end),2))];
            %     app.Label_33.Text =num2str(round(app.datacontainer.PARAMETERS.RESGRFatPeakKAM(pos),2));
            %     %%
            %     app.Label_4.Text = ['Moment arm: ', num2str(round(app.datacontainer.PARAMETERS.MOMENTARMatPeakKAM(end),2))];
            %     app.Label_44.Text =num2str(round(app.datacontainer.PARAMETERS.MOMENTARMatPeakKAM(pos),2));
            %     %%
            %     app.Label_5.Text = ['Contact time: ', num2str(round(app.datacontainer.PARAMETERS.CONTACT_TIME(end),3))];
            %     app.Label_55.Text =num2str(round(app.datacontainer.PARAMETERS.CONTACT_TIME(pos),3));
            %     %%
            %     app.Label_6.Text = ['pKAM: ', num2str(round(app.datacontainer.PARAMETERS.PEAK_KNEE_X(end),2))];
            %     app.Label_66.Text =num2str(round(app.datacontainer.PARAMETERS.PEAK_KNEE_X(pos),2));
            %
            %     app.current.Text = app.datacontainer.NORMAL.HEADER  {end};
            %     app.high.Text =app.datacontainer.NORMAL.HEADER  {pos};
            % else
            %     %% plotte mean mit feedback
            %     [~, pos]=max(app.datacontainer.PARAMETERS.PEAK_KNEE_X);
            %     %% add textlabels
            %     app.Label_1.Text = ['Cutting Angle: ', num2str(round(app.datacontainer.PARAMETERS.CuttingAngle(end),1))];
            %     app.Label_11.Text =num2str(round(app.datacontainer.PARAMETERS.CuttingAngle(pos),1));
            %     %%
            %     app.Label_2.Text = ['Approach Speed: ', num2str(round(app.datacontainer.PARAMETERS.VELOCITY(end),1))];
            %     app.Label_22.Text =num2str(round(app.datacontainer.PARAMETERS.VELOCITY(pos),1));
            %     %%
            %     app.Label_3.Text = ['ResGRF: ', num2str(round(app.datacontainer.PARAMETERS.RESGRFatPeakKAM(end),2))];
            %     app.Label_33.Text =num2str(round(app.datacontainer.PARAMETERS.RESGRFatPeakKAM(pos),2));
            %     %%
            %     app.Label_4.Text = ['Moment arm: ', num2str(round(app.datacontainer.PARAMETERS.MOMENTARMatPeakKAM(end),2))];
            %     app.Label_44.Text =num2str(round(app.datacontainer.PARAMETERS.MOMENTARMatPeakKAM(pos),2));
            %     %%
            %     app.Label_5.Text = ['Contact time: ', num2str(round(app.datacontainer.PARAMETERS.CONTACT_TIME(end),3))];
            %     app.Label_55.Text =num2str(round(app.datacontainer.PARAMETERS.CONTACT_TIME(pos),3));
            %     %%
            %     app.Label_6.Text = ['pKAM: ', num2str(round(app.datacontainer.PARAMETERS.PEAK_KNEE_X(end),2))];
            %     app.Label_66.Text =num2str(round(app.datacontainer.PARAMETERS.PEAK_KNEE_X(pos),2));
            %
            %     app.current.Text = app.datacontainer.NORMAL.HEADER  {end};
            %     app.high.Text =app.datacontainer.NORMAL.HEADER  {pos};
            %
            % end


        end

        % Value changed function: DropDown_1
        function DropDown_1ValueChanged(app, event)
            update_subplots(app,'UIAxes1', 'DropDown_1')
        end

        % Callback function
        function EditField_1OValueChanged(app, event)
            %             app= add_patch_interactive(app,'EditField_1O', 'EditField_1U', 'UIAxes1', 'handle1');
        end

        % Callback function
        function EditField_1UValueChanged(app, event)
            %             app= add_patch_interactive(app,'EditField_1O', 'EditField_1U', 'UIAxes1', 'handle1');
        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
            update_subplots(app,'UIAxes2', 'DropDown_2')

        end

        % Value changed function: DropDown_3
        function DropDown_3ValueChanged(app, event)
            update_subplots(app,'UIAxes3', 'DropDown_3')

        end

        % Value changed function: DropDown_4
        function DropDown_4ValueChanged(app, event)
            update_subplots(app,'UIAxes4', 'DropDown_4')

        end

        % Value changed function: DropDown_5
        function DropDown_5ValueChanged(app, event)
            cla(app.UIAxes5);
            cla(app.UIAxes5_90);
            bar_sub_plot(app.DropDown_5.Value,'UIAxes5_90', 'UIAxes5', app.datastorage.matrix,  app.datastorage.idxr_180, app.datastorage.idxl_180,app.datastorage.idxr_90, app.datastorage.idxl_90,app.datastorage.no_trials, app.datastorage.col ,'', '', app.datastorage.idxr_180_w_f,app.datastorage.idxl_180_w_f,app.datastorage.idxr_90_w_f,app.datastorage.idxl_90_w_f,app)

            %update_subplots(app,'UIAxes5', 'DropDown_5')

        end

        % Value changed function: DropDown_6
        function DropDown_6ValueChanged(app, event)
            update_subplots(app,'UIAxes6', 'DropDown_6')

        end

        % Value changed function: EditField_1O_2, EditField_1U_2
        function EditField_1O_2ValueChanged(app, event)
            %             app= add_patch_interactive(app,'EditField_1O_2', 'EditField_1U_2', 'UIAxes2', 'handle2');

        end

        % Callback function
        function EditField_1O_3ValueChanged(app, event)
            %             app= add_patch_interactive(app,'EditField_1O_3', 'EditField_1U_3', 'UIAxes3', 'handle3');

        end

        % Callback function
        function EditField_1O_4ValueChanged(app, event)

            %             app= add_patch_interactive(app,'EditField_1O_4', 'EditField_1U_4', 'UIAxes4', 'handle4');

        end

        % Value changed function: EditField_1O_5, EditField_1U_5
        function EditField_1O_5ValueChanged(app, event)
            %             app= add_patch_interactive(app,'EditField_1O_5', 'EditField_1U_5', 'UIAxes5', 'handle5');
        end

        % Value changed function: DropDown_7
        function DropDown_7ValueChanged(app, event)
            trialnames = fieldnames(app.datacontainer.GRF_TABLE);


            % app.DropDown_7.Items = trialnames;
            % app.DropDown_7.Value = (trialnames{end, 1});

            %make_stick_figure_new(app.datacontainer.OPTIONS, app.datacontainer.ANALYZEDLEG.(app.DropDown_7.Value),app.datacontainer.MARKERS, app.datacontainer.FP, app.datacontainer.WINKEL, app.datacontainer.KINETICS, (app.DropDown_7.Value), app.datacontainer.kneefrontalplanemoment, app.datacontainer.CONTACT,  app.datacontainer.CONTACT_downsampled, app)
            %make_stick_figure_new(app.datacontainer.OPTIONS, app.datacontainer.OPTIONS.ANALYZEDLEG.(app.DropDown_7.Value),app.datacontainer.MARKERS, app.datacontainer.OPTIONS.ForcePlateNumber, (app.DropDown_7.Value), app.datacontainer.CONTACT_KINEMATIC, app, app.datacontainer.NORMAL, app.datacontainer.BK_POS_TABLE, app.datacontainer.GRF_TABLE, app.datacontainer.MOMENT_TABLE)

            index_current_struct = find(strcmp(app.datacontainer.NORMAL.HEADER,(app.DropDown_7.Value) ) ==1);
            plot(app.UIAxes6, app.datacontainer.NORMAL.KAM(:,index_current_struct))
            ylabel(app.UIAxes6, 'KAM [Nm/kg]', 'Interpreter','none', 'FontWeight', 'bold');


        end

        % Button pushed function: LoadFile
        function LoadFileButtonPushed(app, event)


            [file,location] = uigetfile('*.mat','Select File');
            app.datacontainer =load ([location, '/', file]);
            path_to_file = [location, '/', file];

            startupFcn(app, app.datacontainer )

        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)

            try
                app.selected.Text = app.datacontainer.NORMAL.HEADER{app.EditField.Value};
            catch
            end
        end

        % Button pushed function: xButton
        function xButtonPushed(app, event)
            %% last trialname
            all_trialnames = fieldnames (app.datacontainer.OPTIONS.WAS_VALID);
            trialname_delete = (all_trialnames{end, 1});
            % Define a function to recursively loop through the structs
            % Call the function to start the recursive looping
            [app.datacontainer]=recursiveStructLoop(app.datacontainer, trialname_delete);
            [app.datacontainer.OPTIONS]=recursiveStructLoop(app.datacontainer.OPTIONS, trialname_delete);
            n_names = fieldnames(app.datacontainer.NORMAL);
            for n = 1:length(n_names)
                app.datacontainer.NORMAL.(n_names{n, 1})(:,end) = [];
            end

            n_names = fieldnames(app.datacontainer.PARAMETERS);
            for n = 1:length(n_names)
                app.datacontainer.PARAMETERS.(n_names{n, 1})(:,end) = [];
            end
            %% save the mat file a reload the dasboard
            DATA = app.datacontainer;
            %app.datacontainer.DATA.OPTIONS.MAT_FILE_LOCATION= 'C:\OLE_APP_stable/P00X.mat'
            save([app.datacontainer.OPTIONS.MAT_FILE_LOCATION], '-struct', 'DATA');
            % delete(findall(0, 'Type', 'figure', 'Name', 'Dashboard'));
            Dashboard([app.datacontainer.OPTIONS.MAT_FILE_LOCATION]);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            a = 2;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DashboardUIFigure = uifigure('Visible', 'off');
            app.DashboardUIFigure.Name = 'Dashboard';
            app.UIAxes3 = uiaxes(app.DashboardUIFigure);
            xlabel(app.UIAxes3, ' ')
            ylabel(app.UIAxes3, ' ')
            zlabel(app.UIAxes3, ' ')
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.UIAxes4 = uiaxes(app.DashboardUIFigure);
            xlabel(app.UIAxes4, ' ')
            ylabel(app.UIAxes4, ' ')
            zlabel(app.UIAxes4, ' ')
            app.UIAxes4.XTick = [];
            app.UIAxes4.YTick = [];
            app.UIAxes5 = uiaxes(app.DashboardUIFigure);
            xlabel(app.UIAxes5, ' ')
            ylabel(app.UIAxes5, ' ')
            zlabel(app.UIAxes5, ' ')
            app.UIAxes5.XTick = [];
            app.UIAxes5.YTick = [];
            app.UIAxes22 = uiaxes(app.DashboardUIFigure);
            app.UIAxes22.XTick = [];
            app.UIAxes22.YTick = [];
            app.UIAxes22.ZTick = [];
            app.UIAxes6 = uiaxes(app.DashboardUIFigure);
            xlabel(app.UIAxes6, ' ')
            ylabel(app.UIAxes6, ' ')
            zlabel(app.UIAxes6, ' ')
            app.UIAxes6.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes6.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes2 = uiaxes(app.DashboardUIFigure);
            xlabel(app.UIAxes2, ' ')
            ylabel(app.UIAxes2, ' ')
            zlabel(app.UIAxes2, ' ')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes3_90 = uiaxes(app.DashboardUIFigure);
            xlabel(app.UIAxes3_90, ' ')
            ylabel(app.UIAxes3_90, ' ')
            zlabel(app.UIAxes3_90, ' ')
            app.UIAxes3_90.XTick = [];
            app.UIAxes3_90.YTick = [];
            app.UIAxes4_90 = uiaxes(app.DashboardUIFigure);
            xlabel(app.UIAxes4_90, ' ')
            ylabel(app.UIAxes4_90, ' ')
            zlabel(app.UIAxes4_90, ' ')
            app.UIAxes4_90.XTick = [];
            app.UIAxes4_90.YTick = [];
            app.UIAxes5_90 = uiaxes(app.DashboardUIFigure);
            xlabel(app.UIAxes5_90, ' ')
            ylabel(app.UIAxes5_90, ' ')
            zlabel(app.UIAxes5_90, ' ')
            app.UIAxes5_90.XTick = [];
            app.UIAxes5_90.YTick = [];
            app.UIAxes2_90 = uiaxes(app.DashboardUIFigure);
            xlabel(app.UIAxes2_90, ' ')
            ylabel(app.UIAxes2_90, ' ')
            zlabel(app.UIAxes2_90, ' ')
            app.UIAxes2_90.XTick = [];
            app.UIAxes2_90.YTick = [];
            app.UIAxes1 = uiaxes(app.DashboardUIFigure);
            ylabel(app.UIAxes1, ' ')
            zlabel(app.UIAxes1, ' ')
            app.UIAxes1.XTick = [];
            app.UIAxes1.YTick = 1;
            app.UIAxes1_90 = uiaxes(app.DashboardUIFigure);
            ylabel(app.UIAxes1_90, ' ')
            zlabel(app.UIAxes1_90, ' ')
            app.UIAxes1_90.XTick = [];
            app.UIAxes1_90.YTick = 1;
            app.UIAxes6_90 = uiaxes(app.DashboardUIFigure);
            xlabel(app.UIAxes6_90, ' ')
            ylabel(app.UIAxes6_90, ' ')
            zlabel(app.UIAxes6_90, ' ')
            app.UIAxes6_90.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes6_90.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.EditField_1O_5 = uieditfield(app.DashboardUIFigure, 'numeric');
            app.EditField_1O_5.ValueChangedFcn = createCallbackFcn(app, @EditField_1O_5ValueChanged, true);
            app.EditField_1O_5.Visible = 'off';
            app.EditField_1O_5.Value = 5;
            app.EditField_1U_5 = uieditfield(app.DashboardUIFigure, 'numeric');
            app.EditField_1U_5.ValueChangedFcn = createCallbackFcn(app, @EditField_1O_5ValueChanged, true);
            app.EditField_1U_5.Visible = 'off';
            app.EditField_1U_5.Value = 1.3;
            app.DropDown_3 = uidropdown(app.DashboardUIFigure);
            app.DropDown_3.ValueChangedFcn = createCallbackFcn(app, @DropDown_3ValueChanged, true);
            app.DropDown_3.Visible = 'off';
            app.DropDown_4 = uidropdown(app.DashboardUIFigure);
            app.DropDown_4.ValueChangedFcn = createCallbackFcn(app, @DropDown_4ValueChanged, true);
            app.DropDown_4.Visible = 'off';
            app.DropDown_5 = uidropdown(app.DashboardUIFigure);
            app.DropDown_5.ValueChangedFcn = createCallbackFcn(app, @DropDown_5ValueChanged, true);
            app.Label_1 = uilabel(app.DashboardUIFigure);
            app.Label_1.Text = ' ';
            app.DropDown_1 = uidropdown(app.DashboardUIFigure);
            app.DropDown_1.ValueChangedFcn = createCallbackFcn(app, @DropDown_1ValueChanged, true);
            app.DropDown_1.Visible = 'off';
            app.DropDown_2 = uidropdown(app.DashboardUIFigure);
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_2.Visible = 'off';
            app.DropDown_6 = uidropdown(app.DashboardUIFigure);
            app.DropDown_6.ValueChangedFcn = createCallbackFcn(app, @DropDown_6ValueChanged, true);
            app.DropDown_6.Visible = 'off';
            app.EditField_1O_2 = uieditfield(app.DashboardUIFigure, 'numeric');
            app.EditField_1O_2.ValueChangedFcn = createCallbackFcn(app, @EditField_1O_2ValueChanged, true);
            app.EditField_1O_2.Visible = 'off';
            app.EditField_1O_2.Value = 5;
            app.EditField_1U_2 = uieditfield(app.DashboardUIFigure, 'numeric');
            app.EditField_1U_2.ValueChangedFcn = createCallbackFcn(app, @EditField_1O_2ValueChanged, true);
            app.EditField_1U_2.Visible = 'off';
            app.EditField_1U_2.Value = 1.3;
            app.DropDown_7 = uidropdown(app.DashboardUIFigure);
            app.DropDown_7.ValueChangedFcn = createCallbackFcn(app, @DropDown_7ValueChanged, true);
            app.DropDown_7.Visible = 'off';
            app.LoadFile = uibutton(app.DashboardUIFigure, 'push');
            app.LoadFile.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadFile.Text = 'Browse';
            app.Label_2 = uilabel(app.DashboardUIFigure);
            app.Label_2.Text = ' ';
            app.Label_3 = uilabel(app.DashboardUIFigure);
            app.Label_3.Text = ' ';
            app.Label_4 = uilabel(app.DashboardUIFigure);
            app.Label_4.Text = ' ';
            app.Label_5 = uilabel(app.DashboardUIFigure);
            app.Label_5.Text = ' ';
            app.TechniqueVariables180cutsLabel = uilabel(app.DashboardUIFigure);
            app.TechniqueVariables180cutsLabel.Text = 'Technique Variables 180° cuts';
            app.TechniqueVariables90cutsLabel = uilabel(app.DashboardUIFigure);
            app.TechniqueVariables90cutsLabel.Text = 'Technique Variables 90° cuts';
            app.Label_22 = uilabel(app.DashboardUIFigure);
            app.Label_22.Text = ' ';
            app.Label_33 = uilabel(app.DashboardUIFigure);
            app.Label_33.Text = ' ';
            app.Label_44 = uilabel(app.DashboardUIFigure);
            app.Label_44.Text = ' ';
            app.Label_55 = uilabel(app.DashboardUIFigure);
            app.Label_55.Text = ' ';
            app.Label_11 = uilabel(app.DashboardUIFigure);
            app.Label_11.Text = ' ';
            app.DropDown_8 = uidropdown(app.DashboardUIFigure);
            app.DropDown_8.Visible = 'off';
            app.DropDown_9 = uidropdown(app.DashboardUIFigure);
            app.DropDown_9.Visible = 'off';
            app.DropDown_10 = uidropdown(app.DashboardUIFigure);
            app.Kneeabductionmoment180cutsLabel = uilabel(app.DashboardUIFigure);
            app.Kneeabductionmoment180cutsLabel.Text = 'Knee abduction moment 180° cuts';
            app.DropDown_11 = uidropdown(app.DashboardUIFigure);
            app.DropDown_11.Visible = 'off';
            app.Label_6 = uilabel(app.DashboardUIFigure);
            app.Label_6.Text = ' ';
            app.Label_66 = uilabel(app.DashboardUIFigure);
            app.Label_66.Text = ' ';
            app.current = uilabel(app.DashboardUIFigure);
            app.current.Text = ' ';
            app.high = uilabel(app.DashboardUIFigure);
            app.high.Text = ' ';
            app.selected = uilabel(app.DashboardUIFigure);
            app.selected.Text = ' ';
            app.EditField = uieditfield(app.DashboardUIFigure, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.xButton = uibutton(app.DashboardUIFigure, 'push');
            app.xButton.ButtonPushedFcn = createCallbackFcn(app, @xButtonPushed, true);
            app.xButton.Text = 'x';
            app.Kneeabductionmoment90cutsLabel = uilabel(app.DashboardUIFigure);
            app.Kneeabductionmoment90cutsLabel.Text = 'Knee abduction moment 90° cuts';
            app.Label_7 = uilabel(app.DashboardUIFigure);
            app.Label_7.Text = ' ';
            app.Label_8 = uilabel(app.DashboardUIFigure);
            app.Label_8.Text = ' ';
            app.Label_9 = uilabel(app.DashboardUIFigure);
            app.Label_9.Text = ' ';
            app.Label_10 = uilabel(app.DashboardUIFigure);
            app.Label_10.Text = ' ';
            app.Label_1111 = uilabel(app.DashboardUIFigure);
            app.Label_1111.Text = ' ';
            app.Label_88 = uilabel(app.DashboardUIFigure);
            app.Label_88.Text = ' ';
            app.Label_99 = uilabel(app.DashboardUIFigure);
            app.Label_99.Text = ' ';
            app.Label_1010 = uilabel(app.DashboardUIFigure);
            app.Label_1010.Text = ' ';
            app.Label_111111 = uilabel(app.DashboardUIFigure);
            app.Label_111111.Text = ' ';
            app.Label_1212 = uilabel(app.DashboardUIFigure);
            app.Label_1212.Text = ' ';
            app.Label_12 = uilabel(app.DashboardUIFigure);
            app.Label_12.Text = ' ';
            app.Label_78 = uilabel(app.DashboardUIFigure);
            app.Label_78.Text = ' ';
            app.current_2 = uilabel(app.DashboardUIFigure);
            app.current_2.Text = ' ';
            app.high_2 = uilabel(app.DashboardUIFigure);
            app.high_2.Text = ' ';
            app.selected_2 = uilabel(app.DashboardUIFigure);
            app.selected_2.Text = ' ';
            app.EditField_6 = uieditfield(app.DashboardUIFigure, 'numeric');
            app.SaveButton = uibutton(app.DashboardUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Visible = 'off';
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.DashboardUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Dashboard(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DashboardUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DashboardUIFigure)
        end
    end
end